classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrocarButton                    matlab.ui.control.Button
        AbrirButton                     matlab.ui.control.Button
        TipoGraficoListBox              matlab.ui.control.ListBox
        TipoGraficoListBoxLabel         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        PassaBaixaTab                   matlab.ui.container.Tab
        VerificarButton                 matlab.ui.control.Button
        LarguradabandadetransioEditField  matlab.ui.control.NumericEditField
        LarguradabandadetransioEditFieldLabel  matlab.ui.control.Label
        FrequnciadecorteEditField       matlab.ui.control.NumericEditField
        FrequnciadecorteEditFieldLabel  matlab.ui.control.Label
        FrequnciadeamostragemEditField  matlab.ui.control.NumericEditField
        FrequnciadeamostragemEditFieldLabel  matlab.ui.control.Label
        ParmetrosdofiltropassabaixaLabel  matlab.ui.control.Label
        ExecutarButton                  matlab.ui.control.Button
        JanelamentosListBox             matlab.ui.control.ListBox
        JanelamentosListBoxLabel        matlab.ui.control.Label
        MnimanabandaderejeioRsEditField  matlab.ui.control.NumericEditField
        MnimanabandaderejeioRsEditFieldLabel  matlab.ui.control.Label
        MximanabandadepassagemRpEditField  matlab.ui.control.NumericEditField
        MximanabandadepassagemRpEditFieldLabel  matlab.ui.control.Label
        OscilaesLabel                   matlab.ui.control.Label
        PassaAltaTab                    matlab.ui.container.Tab
        LarguradabandadetransioEditField_pa  matlab.ui.control.NumericEditField
        LarguradabandadetransioEditField_2Label  matlab.ui.control.Label
        FrequnciadecorteEditField_pa    matlab.ui.control.NumericEditField
        FrequnciadecorteEditField_2Label  matlab.ui.control.Label
        FrequnciadeamostragemEditField_pa  matlab.ui.control.NumericEditField
        FrequnciadeamostragemEditField_2Label  matlab.ui.control.Label
        JanelamentosListBox_hp          matlab.ui.control.ListBox
        JanelamentosListBox_2Label      matlab.ui.control.Label
        MnimanabandaderejeioRsEditField_hp  matlab.ui.control.NumericEditField
        MnimanabandaderejeioRsEditField_2Label  matlab.ui.control.Label
        MximanabandadepassagemRpEditField_hp  matlab.ui.control.NumericEditField
        MximanabandadepassagemRpEditField_2Label  matlab.ui.control.Label
        VerificarButton_2               matlab.ui.control.Button
        ParmetrosdofiltropassaaltaLabel  matlab.ui.control.Label
        ExecutarButton_hp               matlab.ui.control.Button
        OscilaesLabel_2                 matlab.ui.control.Label
        PassaFaixaTab                   matlab.ui.container.Tab
        LarguradabandadepassagemEditField_pf  matlab.ui.control.NumericEditField
        LarguradabandadepassagemLabel   matlab.ui.control.Label
        LarguradabandadetransioEditField_pf  matlab.ui.control.NumericEditField
        LarguradabandadetransioEditField_3Label  matlab.ui.control.Label
        FrequnciacentralEditField_pf    matlab.ui.control.NumericEditField
        FrequnciacentralEditFieldLabel  matlab.ui.control.Label
        FrequnciadeamostragemEditField_pf  matlab.ui.control.NumericEditField
        FrequnciadeamostragemEditField_3Label  matlab.ui.control.Label
        JanelamentosListBox_pf          matlab.ui.control.ListBox
        JanelamentosListBox_2Label_2    matlab.ui.control.Label
        MnimanabandaderejeioRsEditField_pf  matlab.ui.control.NumericEditField
        MnimanabandaderejeioRsEditField_2Label_2  matlab.ui.control.Label
        MximanabandadepassagemRpEditField_pf  matlab.ui.control.NumericEditField
        MximanabandadepassagemRpEditField_2Label_2  matlab.ui.control.Label
        VerificarButton_3               matlab.ui.control.Button
        ParmetrosdofiltropassafaixaLabel  matlab.ui.control.Label
        ExecutarButton_pf               matlab.ui.control.Button
        OscilaesLabel_3                 matlab.ui.control.Label
        RejeitaFaixaTab                 matlab.ui.container.Tab
        LarguradabandadepassagemEditField_rf  matlab.ui.control.NumericEditField
        LarguradabandaderejeioLabel     matlab.ui.control.Label
        LarguradabandadetransioEditField_rf  matlab.ui.control.NumericEditField
        LarguradabandadetransioEditField_4Label  matlab.ui.control.Label
        FrequnciacentralEditField_rf    matlab.ui.control.NumericEditField
        FrequnciacentralEditField_2Label  matlab.ui.control.Label
        FrequnciadeamostragemEditField_rf  matlab.ui.control.NumericEditField
        FrequnciadeamostragemEditField_4Label  matlab.ui.control.Label
        JanelamentosListBox_rf          matlab.ui.control.ListBox
        JanelamentosListBox_2Label_3    matlab.ui.control.Label
        MnimanabandaderejeioRsEditField_rf  matlab.ui.control.NumericEditField
        MnimanabandaderejeioRsEditField_2Label_3  matlab.ui.control.Label
        MximanabandadepassagemRpEditField_rf  matlab.ui.control.NumericEditField
        MximanabandadepassagemRpEditField_2Label_3  matlab.ui.control.Label
        VerificarButton_rf              matlab.ui.control.Button
        ParmetrosdofiltrorejeitafaixaLabel  matlab.ui.control.Label
        ExecutarButton_rf               matlab.ui.control.Button
        OscilaesLabel_4                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        M; % Description
        w;
        W;
        hd;
        hn;
        freq_s;
        N;
        n;
        
        delta_blackman   = 0.0001;
        delta_hamming    = 0.0022;
        delta_hann       = 0.0063;
        delta_bartlett   = 0.0562;
        delta_retangular = 0.0891;
        
        janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])]
        janelas_bkp = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])]
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExecutarButton
        function chama_passabaixa(app, event)
            
            
            app.TrocarButton.Enable = true;
            app.AbrirButton.Enable = true;
            %% parametros do filtro
            app.freq_s = app.FrequnciadeamostragemEditField.Value;
            
            fc = app.FrequnciadecorteEditField.Value;
            
            bw_hz = app.LarguradabandadetransioEditField.Value;
            
            rp = app.MximanabandadepassagemRpEditField.Value;
            
            rs = app.MnimanabandaderejeioRsEditField.Value;
            
            
            fp = fc - bw_hz/2; % frequência limite da banda de passagem 
            fs = fc + bw_hz/2; % frequência limite da banda de transição


            ws = (2*pi*fs)/app.freq_s;
            wp = (2*pi*fp)/app.freq_s;
            wc = (ws+wp)/2;
            %Bw = abs((ws - wp))/(2*pi); % largura do lóbulo principal 
            
            dF = ((bw_hz * (2*pi))/app.freq_s)/(2*pi); % Largura da banda de transição normalizada
            
            
            getJanelamento = get(app.JanelamentosListBox,'Value');
            
            
                index = str2num(getJanelamento(1));
                
                valor_selecionado = string(app.janelas(index))
                
                
                if valor_selecionado == 'Retangular'
                    app.N  = ceil(0.9/(dF));   % Comprimento da janela
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = rectangle_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Bartlett'
                    app.N  = ceil(3.05/(dF));   % Comprimento da janela
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = bartlett_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Hann'
                    app.N  = ceil(3.1/(dF));   % Comprimento da janela
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hann_pbl(app.M);
                elseif valor_selecionado == 'Hamming'
                    app.N  = ceil(3.3/(dF));   % Comprimento da janela
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hamming_pbl(app.M);
                elseif valor_selecionado == 'Blackman'
                    app.N  = ceil(5.5/(dF));   % Comprimento da janela
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = blackman_pbl(app.M);
                end


            %% Calc resposta impulso ideal
            app.n  = 0:app.M; % Vetor de tempo discreto.  
            app.hd = PB_ideal(wc,app.M); % Resposta ao impulso ideal;
                
                % Truncamento da resposta ao impulso ideal.


                app.hn = app.hd.*app.w;
                
                v_hn = int32(app.hn'*10000);
                
                for i = 1:length(app.hn)
                    
                    vetorhnString = ['h[',num2str((i-1)),'] = ', num2str(v_hn(i)), ';'];
                    %vetorhnString = ['h(',num2str((i)),') = ', num2str(v_hn(i)), ';'];
                    
                    disp(vetorhnString)
                end
                
                
            getGrafico = get(app.TipoGraficoListBox,'Value');


            switch(getGrafico(1))
                case '1'
                    title (app.UIAxes,"Janela", 'Interpreter','LaTex','FontSize',12);
                    %axis(app.UIAxes, [0 (app.M) 0 1.1]);
                    stem(app.UIAxes,app.W,app.w);
                case '2'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude (resposta truncada)", 'Interpreter','LaTex','FontSize',12);
                    % app.UIAxes.XLabel = "Frequência (Hz)";
                    %ylabel (app.UIAxes,"Magnitude (DB)");
                    plot(app.UIAxes,f*app.freq_s/(2*pi), 20*log10(abs(h)));
            
                case '3'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    plot(app.UIAxes,(f*app.freq_s)/(2*pi),  (unwrap(angle(h)))*(180/pi) );

                    %freqz(app.hn);
                case '4'
                    title (app.UIAxes,"Coeficientes Ideais", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hd)
                case '5'
                    title (app.UIAxes,"Coeficientes Janelados", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hn)
                case '6'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude ideal e truncada", 'Interpreter','LaTex','FontSize',12);
                    [h2,f2] = freqz(app.hd);
                    x_plo = (f*app.freq_s)/(2*pi);
                    
                    plot(app.UIAxes,x_plo, 20*log10(abs(h)),x_plo,20*log10(abs(h2)))
                    
                    legend(app.UIAxes,'Truncado', 'Ideal')
                case '7'
                    [h,f] = freqz(app.hn);
                    [h2,f2] = freqz(app.hd);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    x_plo = (f*app.freq_s)/(2*pi);
                    plot(app.UIAxes,x_plo,  (unwrap(angle(h)))*(180/pi),x_plo, (unwrap(angle(h2)))*(180/pi) );
                    legend(app.UIAxes,'Truncado', 'Ideal')
            end
            
            
        end

        % Button pushed function: TrocarButton
        function trocarGrafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case '1'
                    title (app.UIAxes,"Janela", 'Interpreter','LaTex','FontSize',12);
                    %axis(app.UIAxes, [0 (app.M) 0 1.1]);
                    stem(app.UIAxes,app.W,app.w);
                case '2'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude (resposta truncada)", 'Interpreter','LaTex','FontSize',12);
                    % app.UIAxes.XLabel = "Frequência (Hz)";
                    %ylabel (app.UIAxes,"Magnitude (DB)");
                    plot(app.UIAxes,f*app.freq_s/(2*pi), 20*log10(abs(h)));
            
                case '3'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    plot(app.UIAxes,(f*app.freq_s)/(2*pi),  (unwrap(angle(h)))*(180/pi) );

                    %freqz(app.hn);
                case '4'
                    title (app.UIAxes,"Coeficientes Ideais", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hd)
                case '5'
                    title (app.UIAxes,"Coeficientes Janelados", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hn)
                case '6'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude ideal e truncada", 'Interpreter','LaTex','FontSize',12);
                    [h2,f2] = freqz(app.hd);
                    x_plo = (f*app.freq_s)/(2*pi);
                    
                    plot(app.UIAxes,x_plo, 20*log10(abs(h)),x_plo,20*log10(abs(h2)))
                    
                    legend(app.UIAxes,'Truncado', 'Ideal')
                case '7'
                    [h,f] = freqz(app.hn);
                    [h2,f2] = freqz(app.hd);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    x_plo = (f*app.freq_s)/(2*pi);
                    plot(app.UIAxes,x_plo,  (unwrap(angle(h)))*(180/pi),x_plo, (unwrap(angle(h2)))*(180/pi) );
                    legend(app.UIAxes,'Truncado', 'Ideal')
            end
            
        end

        % Button pushed function: AbrirButton
        function abrir_grafico(app, event)
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            switch(getGrafico(1))
                case '1'
                    title ("Janela", 'Interpreter','LaTex','FontSize',12);
                    %axis(app.UIAxes, [0 (app.M) 0 1.1]);
                    stem(app.W,app.w);
                case '2'
                    [h,f] = freqz(app.hn);
                    title ("Magnitude (resposta truncada)", 'Interpreter','LaTex','FontSize',12);
                    % app.UIAxes.XLabel = "Frequência (Hz)";
                    %ylabel (app.UIAxes,"Magnitude (DB)");
                    plot(f*app.freq_s/(2*pi), 20*log10(abs(h)));
            
                case '3'
                    [h,f] = freqz(app.hn);
                    title ("Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    plot((f*app.freq_s)/(2*pi),  (unwrap(angle(h)))*(180/pi) );

                    %freqz(app.hn);
                case '4'
                    title ("Coeficientes Ideais", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.n,app.hd)
                case '5'
                    title ("Coeficientes Janelados", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.n,app.hn)
                case '6'
                    [h,f] = freqz(app.hn);
                    title ("Magnitude ideal e truncada", 'Interpreter','LaTex','FontSize',12);
                    [h2,f2] = freqz(app.hd);
                    x_plo = (f*app.freq_s)/(2*pi);
                    
                    plot(x_plo, 20*log10(abs(h)),x_plo,20*log10(abs(h2)))
                    
                    legend(app.UIAxes,'Truncado', 'Ideal')
                case '7'
                    [h,f] = freqz(app.hn);
                    [h2,f2] = freqz(app.hd);
                    title ("Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    x_plo = (f*app.freq_s)/(2*pi);
                    plot(x_plo,  (unwrap(angle(h)))*(180/pi),x_plo, (unwrap(angle(h2)))*(180/pi) );
                    legend('Truncado', 'Ideal')
            end
        end

        % Button pushed function: VerificarButton
        function verificar_parametros_lp(app, event)
          
            
            Rp = app.MximanabandadepassagemRpEditField.Value;
            
            Rs = app.MnimanabandaderejeioRsEditField.Value;
            
            delta_rs = (10^(Rp/20)) - 1;
            delta_rp = 10^(Rs/-20);
            
            delta_filter = delta_rs;
            if delta_rp < delta_filter
                delta_filter = delta_rp;
            end
            
            if delta_filter > app.delta_retangular    % todos
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            elseif delta_filter > app.delta_bartlett % só n retangular
                app.janelas = [cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter > app.delta_hann      % só não retangular e bartlett
                app.janelas = [cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_hamming  % só não retangular, bartlett e Hann
                app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_blackman % só não retangular, bartlett, Hann e Hamming
                app.janelas = [cellstr(['Blackman'])];
            else
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                warndlg('Pico do erro de aproximação maior que o recomendado para os janelamentos definidos.','Warning');
            end
            %app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
            
            %app.JanelamentosListBox.Items=[cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            app.JanelamentosListBox.Items=app.janelas;
            
            app.JanelamentosListBox.Enable  = true;
            app.ExecutarButton.Enable = true;
            
            
        end

        % Button pushed function: VerificarButton_2
        function verifica_parametros_hp(app, event)
            Rp = app.MximanabandadepassagemRpEditField_hp.Value;
            
            Rs = app.MnimanabandaderejeioRsEditField_hp.Value;
            
            delta_rs = (10^(Rp/20)) - 1;
            delta_rp = 10^(Rs/-20);
            
            delta_filter = delta_rs;
            if delta_rp < delta_filter
                delta_filter = delta_rp;
            end
            
            if delta_filter > app.delta_retangular    % todos
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            elseif delta_filter > app.delta_bartlett % só n retangular
                app.janelas = [cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter > app.delta_hann      % só não retangular e bartlett
                app.janelas = [cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_hamming  % só não retangular, bartlett e Hann
                app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_blackman % só não retangular, bartlett, Hann e Hamming
                app.janelas = [cellstr(['Blackman'])];
            else
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                warndlg('Pico do erro de aproximação maior que o recomendado para os janelamentos definidos.','Warning');
            end
            %app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
            
            %app.JanelamentosListBox.Items=[cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            app.JanelamentosListBox_hp.Items=app.janelas;
            
            app.JanelamentosListBox_hp.Enable  = true;
            app.ExecutarButton_hp.Enable = true;
            
            
        end

        % Button pushed function: ExecutarButton_hp
        function chama_passalta(app, event)
            
            app.TrocarButton.Enable = true;
            app.AbrirButton.Enable = true;
            %% parametros do filtro
            app.freq_s = app.FrequnciadeamostragemEditField_pa.Value;
            
            fc = app.FrequnciadecorteEditField_pa.Value;
            
            bw_hz = app.LarguradabandadetransioEditField_pa.Value;
            
            rp = app.MximanabandadepassagemRpEditField_hp.Value;
            
            rs = app.MnimanabandaderejeioRsEditField_hp.Value;
            
            
            fp = fc - bw_hz/2; % frequência limite da banda de passagem 
            fs = fc + bw_hz/2; % frequência limite da banda de transição


            ws = (2*pi*fs)/app.freq_s;
            wp = (2*pi*fp)/app.freq_s;
            wc = (ws+wp)/2;


            dF = ((bw_hz * (2*pi))/app.freq_s)/(2*pi); % Largura da banda de transição normalizada
            
            
            getJanelamento = get(app.JanelamentosListBox_hp,'Value');
            
            
                index = str2num(getJanelamento(1));
                
                valor_selecionado = string(app.janelas(index))
                
                
                if valor_selecionado == 'Retangular'
                    app.N  = ceil(0.9/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = rectangle_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Bartlett'
                    app.N  = ceil(3.05/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = bartlett_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Hann'
                    app.N  = ceil(3.1/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hann_pbl(app.M);
                elseif valor_selecionado == 'Hamming'
                    app.N  = ceil(3.3/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hamming_pbl(app.M);
                elseif valor_selecionado == 'Blackman'
                    app.N  = ceil(5.5/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = blackman_pbl(app.M);
                end
                
                
                 %% Calc resposta impulso ideal
                app.n  = 0:app.M; % Vetor de tempo discreto.  
                app.hd = PA_ideal(wc,app.M); % Resposta ao impulso ideal;
            
                
                % Truncamento da resposta ao impulso ideal.
                
                app.hn = app.hd.*app.w;
                
                v_hn = int32(app.hn'*10000);
                
                for i = 1:length(app.hn)
                    
                    vetorhnString = ['h[',num2str((i-1)),'] = ', num2str(v_hn(i)), ';'];
                    %vetorhnString = ['h(',num2str((i)),') = ', num2str(v_hn(i)), ';'];
                    
                    disp(vetorhnString)
                end
                
                
            getGrafico = get(app.TipoGraficoListBox,'Value');
            
            
            switch(getGrafico(1))
                case '1'
                    title (app.UIAxes,"Janela", 'Interpreter','LaTex','FontSize',12);
                    %axis(app.UIAxes, [0 (app.M) 0 1.1]);
                    stem(app.UIAxes,app.W,app.w);
                case '2'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude (resposta truncada)", 'Interpreter','LaTex','FontSize',12);
                    % app.UIAxes.XLabel = "Frequência (Hz)";
                    %ylabel (app.UIAxes,"Magnitude (DB)");
                    plot(app.UIAxes,f*app.freq_s/(2*pi), 20*log10(abs(h)));
            
                case '3'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    plot(app.UIAxes,(f*app.freq_s)/(2*pi),  (unwrap(angle(h)))*(180/pi) );

                    %freqz(app.hn);
                case '4'
                    title (app.UIAxes,"Coeficientes Ideais", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hd)
                case '5'
                    title (app.UIAxes,"Coeficientes Janelados", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hn)
                case '6'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude ideal e truncada", 'Interpreter','LaTex','FontSize',12);
                    [h2,f2] = freqz(app.hd);
                    x_plo = (f*app.freq_s)/(2*pi);
                    
                    plot(app.UIAxes,x_plo, 20*log10(abs(h)),x_plo,20*log10(abs(h2)))
                    
                    legend(app.UIAxes,'Truncado', 'Ideal')
                case '7'
                    [h,f] = freqz(app.hn);
                    [h2,f2] = freqz(app.hd);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    x_plo = (f*app.freq_s)/(2*pi);
                    plot(app.UIAxes,x_plo,  (unwrap(angle(h)))*(180/pi),x_plo, (unwrap(angle(h2)))*(180/pi) );
                    legend(app.UIAxes,'Truncado', 'Ideal')
            end
        end

        % Button pushed function: VerificarButton_3
        function verifica_parametros_pf(app, event)
            Rp = app.MximanabandadepassagemRpEditField_pf.Value;
            
            Rs = app.MnimanabandaderejeioRsEditField_pf.Value;
            
            delta_rs = (10^(Rp/20)) - 1;
            delta_rp = 10^(Rs/-20);
            
            delta_filter = delta_rs;
            if delta_rp < delta_filter
                delta_filter = delta_rp;
            end
            
            if delta_filter > app.delta_retangular    % todos
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            elseif delta_filter > app.delta_bartlett % só n retangular
                app.janelas = [cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter > app.delta_hann      % só não retangular e bartlett
                app.janelas = [cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_hamming  % só não retangular, bartlett e Hann
                app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_blackman % só não retangular, bartlett, Hann e Hamming
                app.janelas = [cellstr(['Blackman'])];
            else
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                warndlg('Pico do erro de aproximação maior que o recomendado para os janelamentos definidos.','Warning');
            end
            %app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
            
            %app.JanelamentosListBox.Items=[cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            app.JanelamentosListBox_pf.Items=app.janelas;
            
            app.JanelamentosListBox_pf.Enable  = true;
            app.ExecutarButton_pf.Enable = true;
        end

        % Button pushed function: VerificarButton_rf
        function verifica_parametros_rf(app, event)
            Rp = app.MximanabandadepassagemRpEditField_rf.Value;
            
            Rs = app.MnimanabandaderejeioRsEditField_rf.Value;
            
            delta_rs = (10^(Rp/20)) - 1;
            delta_rp = 10^(Rs/-20);
            
            delta_filter = delta_rs;
            if delta_rp < delta_filter
                delta_filter = delta_rp;
            end
            
            if delta_filter > app.delta_retangular    % todos
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            elseif delta_filter > app.delta_bartlett % só n retangular
                app.janelas = [cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter > app.delta_hann      % só não retangular e bartlett
                app.janelas = [cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_hamming  % só não retangular, bartlett e Hann
                app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
                
            elseif delta_filter >  app.delta_blackman % só não retangular, bartlett, Hann e Hamming
                app.janelas = [cellstr(['Blackman'])];
            else
                app.janelas = [cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
                warndlg('Pico do erro de aproximação maior que o recomendado para os janelamentos definidos.','Warning');
            end
            %app.janelas = [cellstr(['Hamming']) cellstr(['Blackman'])];
            
            %app.JanelamentosListBox.Items=[cellstr(['Retangular']) cellstr(['Bartlett']) cellstr(['Hann']) cellstr(['Hamming']) cellstr(['Blackman'])];
            app.JanelamentosListBox_rf.Items=app.janelas;
            
            app.JanelamentosListBox_rf.Enable  = true;
            app.ExecutarButton_rf.Enable = true;
        end

        % Button pushed function: ExecutarButton_pf
        function chama_passfaixa(app, event)
            
            app.TrocarButton.Enable = true;
            app.AbrirButton.Enable = true;
            %% parametros do filtro
            app.freq_s = app.FrequnciadeamostragemEditField_pf.Value;
            
            f0 = app.FrequnciacentralEditField_pf.Value;
            
            bt_hz = app.LarguradabandadetransioEditField_pf.Value;
            
            bw_hz = app.LarguradabandadepassagemEditField_pf.Value;
            
            w0 = (2*pi*f0)/app.freq_s;
            
            bw_rad = (2*pi*bw_hz)/app.freq_s; 
            
            bt_rad = (2*pi*bt_hz)/app.freq_s; 
            
            w1 = w0 - (bw_rad/2);
            
            w2 = w0 + (bw_rad/2);
            
            
            w3 = w1 - bt_rad;
            w4 = w2 + bt_rad;
            
            wc2 = w2+ (bt_rad/2);
            wc1 = (w3) +(bt_rad/2);
           
            
            dF = ((bt_hz * (2*pi))/app.freq_s)/(2*pi); % Largura da banda de transição normalizada
            
            
            getJanelamento = get(app.JanelamentosListBox_pf,'Value');
            
            
                index = str2num(getJanelamento(1));
                
                valor_selecionado = string(app.janelas(index))
                
                
                if valor_selecionado == 'Retangular'
                    app.N  = ceil(0.9/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = rectangle_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Bartlett'
                    app.N  = ceil(3.05/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = bartlett_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Hann'
                    app.N  = ceil(3.1/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hann_pbl(app.M);
                elseif valor_selecionado == 'Hamming'
                    app.N  = ceil(3.3/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hamming_pbl(app.M);
                elseif valor_selecionado == 'Blackman'
                    app.N  = ceil(5.5/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = blackman_pbl(app.M);
                end
                
                %% Calc resposta impulso ideal
            app.n  = 0:app.M; % Vetor de tempo discreto.  
            app.hd = PF_ideal(wc1,wc2,app.M); % Resposta ao impulso ideal;
                
                % Truncamento da resposta ao impulso ideal.
                app.hn = app.hd.*app.w;
                
                
            getGrafico = get(app.TipoGraficoListBox,'Value');


            switch(getGrafico(1))
                case '1'
                    title (app.UIAxes,"Janela", 'Interpreter','LaTex','FontSize',12);
                    %axis(app.UIAxes, [0 (app.M) 0 1.1]);
                    stem(app.UIAxes,app.W,app.w);
                case '2'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude (resposta truncada)", 'Interpreter','LaTex','FontSize',12);
                    % app.UIAxes.XLabel = "Frequência (Hz)";
                    %ylabel (app.UIAxes,"Magnitude (DB)");
                    plot(app.UIAxes,f*app.freq_s/(2*pi), 20*log10(abs(h)));
            
                case '3'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    plot(app.UIAxes,(f*app.freq_s)/(2*pi),  (unwrap(angle(h)))*(180/pi) );

                    %freqz(app.hn);
                case '4'
                    title (app.UIAxes,"Coeficientes Ideais", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hd)
                case '5'
                    title (app.UIAxes,"Coeficientes Janelados", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hn)
                case '6'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude ideal e truncada", 'Interpreter','LaTex','FontSize',12);
                    [h2,f2] = freqz(app.hd);
                    x_plo = (f*app.freq_s)/(2*pi);
                    
                    plot(app.UIAxes,x_plo, 20*log10(abs(h)),x_plo,20*log10(abs(h2)))
                    
                    legend(app.UIAxes,'Truncado', 'Ideal')
                case '7'
                    [h,f] = freqz(app.hn);
                    [h2,f2] = freqz(app.hd);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    x_plo = (f*app.freq_s)/(2*pi);
                    plot(app.UIAxes,x_plo,  (unwrap(angle(h)))*(180/pi),x_plo, (unwrap(angle(h2)))*(180/pi) );
                    legend(app.UIAxes,'Truncado', 'Ideal')
            end
        end

        % Button pushed function: ExecutarButton_rf
        function chama_rejeitafaixa(app, event)
            app.TrocarButton.Enable = true;
            app.AbrirButton.Enable = true;
            %% parametros do filtro
            app.freq_s = app.FrequnciadeamostragemEditField_rf.Value;
            
            f0 = app.FrequnciacentralEditField_rf.Value;
            
            bt_hz = app.LarguradabandadetransioEditField_rf.Value;
            
            bw_hz = app.LarguradabandadepassagemEditField_rf.Value;
            
            
            w0 = (2*pi*f0)/app.freq_s;
            
            bw_rad = (2*pi*bw_hz)/app.freq_s; 
            
            bt_rad = (2*pi*bt_hz)/app.freq_s; 


            w1 = w0 - (bw_rad/2);
            
            w2 = w0 + (bw_rad/2);
            
            
            w3 = w1 - bt_rad;
            w4 = w2 + bt_rad;
            
            wc2 = w2+ (bt_rad/2);
            wc1 = (w3) +(bt_rad/2);


            dF = ((bt_hz * (2*pi))/app.freq_s)/(2*pi); % Largura da banda de transição normalizada
            
            
            getJanelamento = get(app.JanelamentosListBox_rf,'Value');
            
            
                index = str2num(getJanelamento(1));
                
                valor_selecionado = string(app.janelas(index))
                
                
                if valor_selecionado == 'Retangular'
                    app.N  = ceil(0.9/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = rectangle_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Bartlett'
                    app.N  = ceil(3.05/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = bartlett_pbl(app.M);
                    app.w = app.w';
                elseif valor_selecionado == 'Hann'
                    app.N  = ceil(3.1/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hann_pbl(app.M);
                elseif valor_selecionado == 'Hamming'
                    app.N  = ceil(3.3/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = hamming_pbl(app.M);
                elseif valor_selecionado == 'Blackman'
                    app.N  = ceil(5.5/(dF));   % Comprimento da janela de Hamming
                    app.M  = app.N-1;              % Ordem do Filtro
                    
                    [app.w,app.W] = blackman_pbl(app.M);
                end
                
                
                %% Calc resposta impulso ideal
            app.n  = 0:app.M; % Vetor de tempo discreto.  
            app.hd = RF_ideal(wc1,wc2,app.M); % Resposta ao impulso ideal;
                
                % Truncamento da resposta ao impulso ideal.
                app.hn = app.hd.*app.w;
                
                
            getGrafico = get(app.TipoGraficoListBox,'Value');


            switch(getGrafico(1))
                case '1'
                    title (app.UIAxes,"Janela", 'Interpreter','LaTex','FontSize',12);
                    %axis(app.UIAxes, [0 (app.M) 0 1.1]);
                    stem(app.UIAxes,app.W,app.w);
                case '2'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude (resposta truncada)", 'Interpreter','LaTex','FontSize',12);
                    % app.UIAxes.XLabel = "Frequência (Hz)";
                    %ylabel (app.UIAxes,"Magnitude (DB)");
                    plot(app.UIAxes,f*app.freq_s/(2*pi), 20*log10(abs(h)));
            
                case '3'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    plot(app.UIAxes,(f*app.freq_s)/(2*pi),  (unwrap(angle(h)))*(180/pi) );

                    %freqz(app.hn);
                case '4'
                    title (app.UIAxes,"Coeficientes Ideais", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hd)
                case '5'
                    title (app.UIAxes,"Coeficientes Janelados", 'Interpreter','LaTex','FontSize',12);
                    
                    stem(app.UIAxes,app.n,app.hn)
                case '6'
                    [h,f] = freqz(app.hn);
                    title (app.UIAxes,"Magnitude ideal e truncada", 'Interpreter','LaTex','FontSize',12);
                    [h2,f2] = freqz(app.hd);
                    x_plo = (f*app.freq_s)/(2*pi);
                    
                    plot(app.UIAxes,x_plo, 20*log10(abs(h)),x_plo,20*log10(abs(h2)))
                    
                    legend(app.UIAxes,'Truncado', 'Ideal')
                case '7'
                    [h,f] = freqz(app.hn);
                    [h2,f2] = freqz(app.hd);
                    title (app.UIAxes,"Fase (resposta truncada)", 'Interpreter','LaTex','FontSize',12);                
                    xlabel = "Frequência (Hz)";
                    ylabel = "Graus";     
                    x_plo = (f*app.freq_s)/(2*pi);
                    plot(app.UIAxes,x_plo,  (unwrap(angle(h)))*(180/pi),x_plo, (unwrap(angle(h2)))*(180/pi) );
                    legend(app.UIAxes,'Truncado', 'Ideal')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vazio')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PassaBaixaTab = uitab(app.TabGroup);
            app.PassaBaixaTab.Title = 'Passa Baixa';
            app.OscilaesLabel = uilabel(app.PassaBaixaTab);
            app.OscilaesLabel.Text = 'Oscilações:';
            app.MximanabandadepassagemRpEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.MximanabandadepassagemRpEditFieldLabel.Text = 'Máxima na banda de passagem (Rp)';
            app.MximanabandadepassagemRpEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.MximanabandadepassagemRpEditField.Value = 0.1;
            app.MnimanabandaderejeioRsEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.MnimanabandaderejeioRsEditFieldLabel.Text = 'Mínima na banda de rejeição (Rs)';
            app.MnimanabandaderejeioRsEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.MnimanabandaderejeioRsEditField.Value = 50;
            app.JanelamentosListBoxLabel = uilabel(app.PassaBaixaTab);
            app.JanelamentosListBoxLabel.Text = 'Janelamentos';
            app.JanelamentosListBox = uilistbox(app.PassaBaixaTab);
            app.JanelamentosListBox.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox.ItemsData = {'1', '2', '3', '4', '5'};
            app.JanelamentosListBox.Enable = 'off';
            app.JanelamentosListBox.Value = '1';
            app.ExecutarButton = uibutton(app.PassaBaixaTab, 'push');
            app.ExecutarButton.ButtonPushedFcn = createCallbackFcn(app, @chama_passabaixa, true);
            app.ExecutarButton.Enable = 'off';
            app.ExecutarButton.Text = 'Executar';
            app.ParmetrosdofiltropassabaixaLabel = uilabel(app.PassaBaixaTab);
            app.ParmetrosdofiltropassabaixaLabel.Text = 'Parâmetros do filtro passa baixa:';
            app.FrequnciadeamostragemEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FrequnciadeamostragemEditFieldLabel.Text = 'Frequência de amostragem';
            app.FrequnciadeamostragemEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.FrequnciadeamostragemEditField.Value = 8000;
            app.FrequnciadecorteEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.FrequnciadecorteEditFieldLabel.Text = 'Frequência de corte';
            app.FrequnciadecorteEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.FrequnciadecorteEditField.Value = 1900;
            app.LarguradabandadetransioEditFieldLabel = uilabel(app.PassaBaixaTab);
            app.LarguradabandadetransioEditFieldLabel.Text = 'Largura da banda de transição';
            app.LarguradabandadetransioEditField = uieditfield(app.PassaBaixaTab, 'numeric');
            app.LarguradabandadetransioEditField.Value = 400;
            app.VerificarButton = uibutton(app.PassaBaixaTab, 'push');
            app.VerificarButton.ButtonPushedFcn = createCallbackFcn(app, @verificar_parametros_lp, true);
            app.VerificarButton.Text = 'Verificar';
            app.PassaAltaTab = uitab(app.TabGroup);
            app.PassaAltaTab.Title = 'Passa Alta';
            app.OscilaesLabel_2 = uilabel(app.PassaAltaTab);
            app.OscilaesLabel_2.Text = 'Oscilações:';
            app.ExecutarButton_hp = uibutton(app.PassaAltaTab, 'push');
            app.ExecutarButton_hp.ButtonPushedFcn = createCallbackFcn(app, @chama_passalta, true);
            app.ExecutarButton_hp.Enable = 'off';
            app.ExecutarButton_hp.Text = 'Executar';
            app.ParmetrosdofiltropassaaltaLabel = uilabel(app.PassaAltaTab);
            app.ParmetrosdofiltropassaaltaLabel.Text = 'Parâmetros do filtro passa alta:';
            app.VerificarButton_2 = uibutton(app.PassaAltaTab, 'push');
            app.VerificarButton_2.ButtonPushedFcn = createCallbackFcn(app, @verifica_parametros_hp, true);
            app.VerificarButton_2.Text = 'Verificar';
            app.MximanabandadepassagemRpEditField_2Label = uilabel(app.PassaAltaTab);
            app.MximanabandadepassagemRpEditField_2Label.Text = 'Máxima na banda de passagem (Rp)';
            app.MximanabandadepassagemRpEditField_hp = uieditfield(app.PassaAltaTab, 'numeric');
            app.MximanabandadepassagemRpEditField_hp.Value = 0.1;
            app.MnimanabandaderejeioRsEditField_2Label = uilabel(app.PassaAltaTab);
            app.MnimanabandaderejeioRsEditField_2Label.Text = 'Mínima na banda de rejeição (Rs)';
            app.MnimanabandaderejeioRsEditField_hp = uieditfield(app.PassaAltaTab, 'numeric');
            app.MnimanabandaderejeioRsEditField_hp.Value = 50;
            app.JanelamentosListBox_2Label = uilabel(app.PassaAltaTab);
            app.JanelamentosListBox_2Label.Text = 'Janelamentos';
            app.JanelamentosListBox_hp = uilistbox(app.PassaAltaTab);
            app.JanelamentosListBox_hp.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox_hp.ItemsData = {'1', '2', '3', '4', '5'};
            app.JanelamentosListBox_hp.Enable = 'off';
            app.JanelamentosListBox_hp.Value = '1';
            app.FrequnciadeamostragemEditField_2Label = uilabel(app.PassaAltaTab);
            app.FrequnciadeamostragemEditField_2Label.Text = 'Frequência de amostragem';
            app.FrequnciadeamostragemEditField_pa = uieditfield(app.PassaAltaTab, 'numeric');
            app.FrequnciadeamostragemEditField_pa.Value = 8000;
            app.FrequnciadecorteEditField_2Label = uilabel(app.PassaAltaTab);
            app.FrequnciadecorteEditField_2Label.Text = 'Frequência de corte';
            app.FrequnciadecorteEditField_pa = uieditfield(app.PassaAltaTab, 'numeric');
            app.FrequnciadecorteEditField_pa.Value = 1900;
            app.LarguradabandadetransioEditField_2Label = uilabel(app.PassaAltaTab);
            app.LarguradabandadetransioEditField_2Label.Text = 'Largura da banda de transição';
            app.LarguradabandadetransioEditField_pa = uieditfield(app.PassaAltaTab, 'numeric');
            app.LarguradabandadetransioEditField_pa.Value = 400;
            app.PassaFaixaTab = uitab(app.TabGroup);
            app.PassaFaixaTab.Title = 'Passa Faixa';
            app.OscilaesLabel_3 = uilabel(app.PassaFaixaTab);
            app.OscilaesLabel_3.Text = 'Oscilações:';
            app.ExecutarButton_pf = uibutton(app.PassaFaixaTab, 'push');
            app.ExecutarButton_pf.ButtonPushedFcn = createCallbackFcn(app, @chama_passfaixa, true);
            app.ExecutarButton_pf.Enable = 'off';
            app.ExecutarButton_pf.Text = 'Executar';
            app.ParmetrosdofiltropassafaixaLabel = uilabel(app.PassaFaixaTab);
            app.ParmetrosdofiltropassafaixaLabel.Text = 'Parâmetros do filtro passa faixa:';
            app.VerificarButton_3 = uibutton(app.PassaFaixaTab, 'push');
            app.VerificarButton_3.ButtonPushedFcn = createCallbackFcn(app, @verifica_parametros_pf, true);
            app.VerificarButton_3.Text = 'Verificar';
            app.MximanabandadepassagemRpEditField_2Label_2 = uilabel(app.PassaFaixaTab);
            app.MximanabandadepassagemRpEditField_2Label_2.Text = 'Máxima na banda de passagem (Rp)';
            app.MximanabandadepassagemRpEditField_pf = uieditfield(app.PassaFaixaTab, 'numeric');
            app.MximanabandadepassagemRpEditField_pf.Value = 0.1;
            app.MnimanabandaderejeioRsEditField_2Label_2 = uilabel(app.PassaFaixaTab);
            app.MnimanabandaderejeioRsEditField_2Label_2.Text = 'Mínima na banda de rejeição (Rs)';
            app.MnimanabandaderejeioRsEditField_pf = uieditfield(app.PassaFaixaTab, 'numeric');
            app.MnimanabandaderejeioRsEditField_pf.Value = 50;
            app.JanelamentosListBox_2Label_2 = uilabel(app.PassaFaixaTab);
            app.JanelamentosListBox_2Label_2.Text = 'Janelamentos';
            app.JanelamentosListBox_pf = uilistbox(app.PassaFaixaTab);
            app.JanelamentosListBox_pf.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox_pf.ItemsData = {'1', '2', '3', '4', '5'};
            app.JanelamentosListBox_pf.Enable = 'off';
            app.JanelamentosListBox_pf.Value = '1';
            app.FrequnciadeamostragemEditField_3Label = uilabel(app.PassaFaixaTab);
            app.FrequnciadeamostragemEditField_3Label.Text = 'Frequência de amostragem';
            app.FrequnciadeamostragemEditField_pf = uieditfield(app.PassaFaixaTab, 'numeric');
            app.FrequnciadeamostragemEditField_pf.Value = 8000;
            app.FrequnciacentralEditFieldLabel = uilabel(app.PassaFaixaTab);
            app.FrequnciacentralEditFieldLabel.Text = 'Frequência central';
            app.FrequnciacentralEditField_pf = uieditfield(app.PassaFaixaTab, 'numeric');
            app.FrequnciacentralEditField_pf.Value = 2000;
            app.LarguradabandadetransioEditField_3Label = uilabel(app.PassaFaixaTab);
            app.LarguradabandadetransioEditField_3Label.Text = 'Largura da banda de transição';
            app.LarguradabandadetransioEditField_pf = uieditfield(app.PassaFaixaTab, 'numeric');
            app.LarguradabandadetransioEditField_pf.Value = 500;
            app.LarguradabandadepassagemLabel = uilabel(app.PassaFaixaTab);
            app.LarguradabandadepassagemLabel.Text = 'Largura da banda de passagem';
            app.LarguradabandadepassagemEditField_pf = uieditfield(app.PassaFaixaTab, 'numeric');
            app.LarguradabandadepassagemEditField_pf.Value = 1000;
            app.RejeitaFaixaTab = uitab(app.TabGroup);
            app.RejeitaFaixaTab.Title = 'Rejeita Faixa';
            app.OscilaesLabel_4 = uilabel(app.RejeitaFaixaTab);
            app.OscilaesLabel_4.Text = 'Oscilações:';
            app.ExecutarButton_rf = uibutton(app.RejeitaFaixaTab, 'push');
            app.ExecutarButton_rf.ButtonPushedFcn = createCallbackFcn(app, @chama_rejeitafaixa, true);
            app.ExecutarButton_rf.Enable = 'off';
            app.ExecutarButton_rf.Text = 'Executar';
            app.ParmetrosdofiltrorejeitafaixaLabel = uilabel(app.RejeitaFaixaTab);
            app.ParmetrosdofiltrorejeitafaixaLabel.Text = 'Parâmetros do filtro rejeita faixa:';
            app.VerificarButton_rf = uibutton(app.RejeitaFaixaTab, 'push');
            app.VerificarButton_rf.ButtonPushedFcn = createCallbackFcn(app, @verifica_parametros_rf, true);
            app.VerificarButton_rf.Text = 'Verificar';
            app.MximanabandadepassagemRpEditField_2Label_3 = uilabel(app.RejeitaFaixaTab);
            app.MximanabandadepassagemRpEditField_2Label_3.Text = 'Máxima na banda de passagem (Rp)';
            app.MximanabandadepassagemRpEditField_rf = uieditfield(app.RejeitaFaixaTab, 'numeric');
            app.MximanabandadepassagemRpEditField_rf.Value = 0.1;
            app.MnimanabandaderejeioRsEditField_2Label_3 = uilabel(app.RejeitaFaixaTab);
            app.MnimanabandaderejeioRsEditField_2Label_3.Text = 'Mínima na banda de rejeição (Rs)';
            app.MnimanabandaderejeioRsEditField_rf = uieditfield(app.RejeitaFaixaTab, 'numeric');
            app.MnimanabandaderejeioRsEditField_rf.Value = 50;
            app.JanelamentosListBox_2Label_3 = uilabel(app.RejeitaFaixaTab);
            app.JanelamentosListBox_2Label_3.Text = 'Janelamentos';
            app.JanelamentosListBox_rf = uilistbox(app.RejeitaFaixaTab);
            app.JanelamentosListBox_rf.Items = {'Retangular', 'Bartlett', 'Hann', 'Hamming', 'Blackman'};
            app.JanelamentosListBox_rf.ItemsData = {'1', '2', '3', '4', '5'};
            app.JanelamentosListBox_rf.Enable = 'off';
            app.JanelamentosListBox_rf.Value = '1';
            app.FrequnciadeamostragemEditField_4Label = uilabel(app.RejeitaFaixaTab);
            app.FrequnciadeamostragemEditField_4Label.Text = 'Frequência de amostragem';
            app.FrequnciadeamostragemEditField_rf = uieditfield(app.RejeitaFaixaTab, 'numeric');
            app.FrequnciadeamostragemEditField_rf.Value = 8000;
            app.FrequnciacentralEditField_2Label = uilabel(app.RejeitaFaixaTab);
            app.FrequnciacentralEditField_2Label.Text = 'Frequência central';
            app.FrequnciacentralEditField_rf = uieditfield(app.RejeitaFaixaTab, 'numeric');
            app.FrequnciacentralEditField_rf.Value = 2000;
            app.LarguradabandadetransioEditField_4Label = uilabel(app.RejeitaFaixaTab);
            app.LarguradabandadetransioEditField_4Label.Text = 'Largura da banda de transição';
            app.LarguradabandadetransioEditField_rf = uieditfield(app.RejeitaFaixaTab, 'numeric');
            app.LarguradabandadetransioEditField_rf.Value = 500;
            app.LarguradabandaderejeioLabel = uilabel(app.RejeitaFaixaTab);
            app.LarguradabandaderejeioLabel.Text = 'Largura da banda de rejeição';
            app.LarguradabandadepassagemEditField_rf = uieditfield(app.RejeitaFaixaTab, 'numeric');
            app.LarguradabandadepassagemEditField_rf.Value = 1000;
            app.TipoGraficoListBoxLabel = uilabel(app.UIFigure);
            app.TipoGraficoListBoxLabel.Text = {'Tipo'; 'Grafico'};
            app.TipoGraficoListBox = uilistbox(app.UIFigure);
            app.TipoGraficoListBox.Items = {'Janela', 'Magnitude truncada', 'Fase truncada', 'Coeficientes ideais', 'Coeficientes janelados', 'Magnitude ideal e magnitude truncada', 'Fase ideal e truncada'};
            app.TipoGraficoListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.TipoGraficoListBox.Value = '1';
            app.AbrirButton = uibutton(app.UIFigure, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @abrir_grafico, true);
            app.AbrirButton.Enable = 'off';
            app.AbrirButton.Text = 'Abrir';
            app.TrocarButton = uibutton(app.UIFigure, 'push');
            app.TrocarButton.ButtonPushedFcn = createCallbackFcn(app, @trocarGrafico, true);
            app.TrocarButton.Enable = 'off';
            app.TrocarButton.Text = 'Trocar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end